classdef ConfigCSVGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CompileButton                   matlab.ui.control.Button
        DefaultButton                   matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        RequiredTab                     matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        parse_filesDropDownLabel        matlab.ui.control.Label
        parse_filesDropDown             matlab.ui.control.DropDown
        label_channelsDropDownLabel     matlab.ui.control.Label
        label_channelsDropDown          matlab.ui.control.DropDown
        create_psthDropDownLabel        matlab.ui.control.Label
        create_psthDropDown             matlab.ui.control.DropDown
        rf_analysisDropDownLabel        matlab.ui.control.Label
        rf_analysisDropDown             matlab.ui.control.DropDown
        make_psth_graphsDropDownLabel   matlab.ui.control.Label
        make_psth_graphsDropDown        matlab.ui.control.DropDown
        nv_analysisDropDownLabel        matlab.ui.control.Label
        nv_analysisDropDown             matlab.ui.control.DropDown
        info_analysisDropDownLabel      matlab.ui.control.Label
        info_analysisDropDown           matlab.ui.control.DropDown
        psth_classifyDropDownLabel      matlab.ui.control.Label
        psth_classifyDropDown           matlab.ui.control.DropDown
        create_mntsDropDownLabel        matlab.ui.control.Label
        create_mntsDropDown             matlab.ui.control.DropDown
        pc_analysisDropDownLabel        matlab.ui.control.Label
        pc_analysisDropDown             matlab.ui.control.DropDown
        ic_analysisDropDownLabel        matlab.ui.control.Label
        ic_analysisDropDown             matlab.ui.control.DropDown
        bin_sizeEditFieldLabel          matlab.ui.control.Label
        bin_sizeEditField               matlab.ui.control.NumericEditField
        pre_timeEditFieldLabel          matlab.ui.control.Label
        pre_timeEditField               matlab.ui.control.NumericEditField
        post_timeEditFieldLabel         matlab.ui.control.Label
        post_timeEditField              matlab.ui.control.NumericEditField
        ignore_animalDropDownLabel      matlab.ui.control.Label
        ignore_animalDropDown           matlab.ui.control.DropDown
        ParserTab                       matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        total_trialsEditFieldLabel      matlab.ui.control.Label
        total_trialsEditField           matlab.ui.control.NumericEditField
        trial_lower_boundEditFieldLabel  matlab.ui.control.Label
        trial_lower_boundEditField      matlab.ui.control.NumericEditField
        total_eventsEditFieldLabel      matlab.ui.control.Label
        total_eventsEditField           matlab.ui.control.NumericEditField
        is_non_strobed_and_strobedDropDown  matlab.ui.control.DropDown
        is_non_strobed_and_strobedDropDownLabel  matlab.ui.control.Label
        event_mapEditFieldLabel         matlab.ui.control.Label
        event_mapEditField              matlab.ui.control.EditField
        PSTHandMNTSTab                  matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        trial_rangeEditFieldLabel       matlab.ui.control.Label
        trial_rangeEditField            matlab.ui.control.EditField
        wanted_eventsEditFieldLabel     matlab.ui.control.Label
        wanted_eventsEditField          matlab.ui.control.EditField
        PCATab                          matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        feature_filterDropDownLabel     matlab.ui.control.Label
        feature_filterDropDown          matlab.ui.control.DropDown
        feature_valueEditFieldLabel     matlab.ui.control.Label
        feature_valueEditField          matlab.ui.control.NumericEditField
        ICATab                          matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        ic_pcEditFieldLabel             matlab.ui.control.Label
        ic_pcEditField                  matlab.ui.control.NumericEditField
        extendedEditFieldLabel          matlab.ui.control.Label
        extendedEditField               matlab.ui.control.NumericEditField
        spheringDropDownLabel           matlab.ui.control.Label
        spheringDropDown                matlab.ui.control.DropDown
        annealEditFieldLabel            matlab.ui.control.Label
        annealEditField                 matlab.ui.control.EditField
        anneal_degEditFieldLabel        matlab.ui.control.Label
        anneal_degEditField             matlab.ui.control.NumericEditField
        stopEditFieldLabel              matlab.ui.control.Label
        stopEditField                   matlab.ui.control.EditField
        max_stepsEditFieldLabel         matlab.ui.control.Label
        max_stepsEditField              matlab.ui.control.NumericEditField
        biasDropDownLabel               matlab.ui.control.Label
        biasDropDown                    matlab.ui.control.DropDown
        momentumEditFieldLabel          matlab.ui.control.Label
        momentumEditField               matlab.ui.control.NumericEditField
        rnd_resetDropDown               matlab.ui.control.DropDown
        rnd_resetDropDownLabel          matlab.ui.control.Label
        verboseEditFieldLabel           matlab.ui.control.Label
        verboseEditField                matlab.ui.control.EditField
        ReceptiveFieldAnalysisTab       matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        spanEditFieldLabel              matlab.ui.control.Label
        spanEditField                   matlab.ui.control.NumericEditField
        threshold_scaleEditFieldLabel   matlab.ui.control.Label
        threshold_scaleEditField        matlab.ui.control.NumericEditField
        sig_binsEditFieldLabel          matlab.ui.control.Label
        sig_binsEditField               matlab.ui.control.NumericEditField
        sig_checkEditFieldLabel         matlab.ui.control.Label
        sig_checkEditField              matlab.ui.control.NumericEditField
        PSTHGraphingTab                 matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        make_region_subplotDropDownLabel  matlab.ui.control.Label
        make_region_subplotDropDown     matlab.ui.control.DropDown
        sub_columnsEditFieldLabel       matlab.ui.control.Label
        sub_columnsEditField            matlab.ui.control.NumericEditField
        sub_rowsEditFieldLabel          matlab.ui.control.Label
        sub_rowsEditField               matlab.ui.control.NumericEditField
        NormalizedVarianceAnalysisTab   matlab.ui.container.Tab
        GridLayout8                     matlab.ui.container.GridLayout
        epsilonEditFieldLabel           matlab.ui.control.Label
        epsilonEditField                matlab.ui.control.NumericEditField
        norm_var_scalingEditFieldLabel  matlab.ui.control.Label
        norm_var_scalingEditField       matlab.ui.control.NumericEditField
        separate_eventsDropDownLabel    matlab.ui.control.Label
        separate_eventsDropDown         matlab.ui.control.DropDown
        PSTHClassifierTab               matlab.ui.container.Tab
        GridLayout9                     matlab.ui.container.GridLayout
        bootstrap_classifierDropDownLabel  matlab.ui.control.Label
        bootstrap_classifierDropDown    matlab.ui.control.DropDown
        boot_iterationsEditFieldLabel   matlab.ui.control.Label
        boot_iterationsEditField        matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        % Used to indicate which variables are then required or not
        is_parser_file_true = 'true';
        is_format_psth_true = 'true';
        is_create_mnts_true = 'true';
        is_pc_analysis_true = 'true';
        is_ic_analysis_true = 'true';
        is_rf_analysis_true = 'true';
        is_make_psth_graphs_true = 'true';
        is_nv_analysis_true = 'true';
        is_psth_classify_true = 'true';
    end
    
    methods (Access = private)
        
        %%%%%%%%%change values to their appropriate default values%%%%%%%%%%
        
        % This callback is for the default button
        function mycallback(app,src,event)
            if(strcmp(event.SelectedOption,'Cancel'))
            elseif (strcmp(event.SelectedOption,'OK'))
                app.parse_filesDropDown.Value = 'true';
                app.label_channelsDropDown.Value = 'true';
                app.create_psthDropDown.Value = 'true';
                app.rf_analysisDropDown.Value = 'true';
                app.make_psth_graphsDropDown.Value = 'true';
                app.nv_analysisDropDown.Value = 'true';
                app.info_analysisDropDown.Value = 'true';
                app.psth_classifyDropDown.Value = 'true';
                app.create_mntsDropDown.Value = 'true';
                app.pc_analysisDropDown.Value = 'true';
                app.ic_analysisDropDown.Value = 'true';
                app.bin_sizeEditField.Value = 0.005;
                app.pre_timeEditField.Value = 0.1;
                app.post_timeEditField.Value = 0.2;
                app.ignore_animalDropDown.Value = 'true';
                app.total_trialsEditField.Value = 100;
                app.trial_lower_boundEditField.Value = 50;
                app.total_eventsEditField.Value = 4;            
                app.is_non_strobed_and_strobedDropDown.Value = 'true'; 
                app.event_mapEditField.Value = '';
                app.trial_rangeEditField.Value = '';
                app.wanted_eventsEditField.Value = '';
                app.spanEditField.Value = 3;
                app.threshold_scaleEditField.Value = 1.65;
                app.sig_binsEditField.Value = 3;
                app.sig_checkEditField.Value = 1;
                app.make_region_subplotDropDown.Value = 'true';
                app.sub_columnsEditField.Value = 50;
                app.sub_rowsEditField.Value = 3;
                app.epsilonEditField.Value = 0.01;
                app.norm_var_scalingEditField.Value = 0.2;
                app.separate_eventsDropDown.Value = 'true';
                app.bootstrap_classifierDropDown.Value = 'true';
                app.boot_iterationsEditField.Value = 50;
                app.feature_filterDropDown.Value = 'percent_var';
                app.feature_valueEditField.Value = 70;
                app.ic_pcEditField.Value = 0;
                app.extendedEditField.Value = 0;
                app.spheringDropDown.Value = 'on';
                app.annealEditField.Value = 'default';
                app.anneal_degEditField.Value = 70;
                app.stopEditField.Value = 'default';
                app.max_stepsEditField.Value = 512;
                app.biasDropDown.Value = 'on';
                app.momentumEditField.Value = 0;
                app.rnd_resetDropDown.Value = 'false';
                app.verboseEditField.Value = 'off';
                
                app.is_parser_file_true = 'true';
                app.is_format_psth_true = 'true';
                app.is_create_mnts_true = 'true';
                app.is_pc_analysis_true = 'true';
                app.is_ic_analysis_true = 'true';
                app.is_rf_analysis_true = 'true';
                app.is_make_psth_graphs_true = 'true';
                app.is_nv_analysis_true = 'true';
                app.is_psth_classify_true = 'true';
                
                checkButtons(app)
            end
        end
        
        % Checks to see whether or not a change should be made for required
        % variables
        function checkButtons(app)
            if(strcmp(app.is_parser_file_true,'true'))
                app.total_trialsEditField.Enable = 'on';
                app.total_trialsEditField.Editable = 'on';
                
                app.trial_lower_boundEditField.Enable = 'on';
                app.trial_lower_boundEditField.Editable = 'on';
                
                app.total_eventsEditField.Enable = 'on';
                app.total_eventsEditField.Editable = 'on';
                
                app.is_non_strobed_and_strobedDropDown.Enable = 'on';
                
                app.event_mapEditField.Enable = 'on';
                app.event_mapEditField.Editable = 'on';
            else
                app.total_trialsEditField.Enable = 'off';
                app.total_trialsEditField.Editable = 'off';
                
                if(strcmp(app.is_format_psth_true,'false'))
                    app.trial_lower_boundEditField.Enable = 'off';
                    app.trial_lower_boundEditField.Editable = 'off';
                end
                
                app.total_eventsEditField.Enable = 'off';
                app.total_eventsEditField.Editable = 'off';
                
                app.is_non_strobed_and_strobedDropDown.Enable = 'off';
                
                app.event_mapEditField.Enable = 'off';
                app.event_mapEditField.Editable = 'off';
            end
            
            if(strcmp(app.is_format_psth_true,'true'))
                app.trial_lower_boundEditField.Enable = 'on';
                app.trial_lower_boundEditField.Editable = 'on';
                
                app.trial_rangeEditField.Enable = 'on';
                app.trial_rangeEditField.Editable = 'on';
                
                app.wanted_eventsEditField.Enable = 'on';
                app.wanted_eventsEditField.Editable = 'on';
            else
                if(strcmp(app.is_parser_file_true,'false'))
                    app.trial_lower_boundEditField.Enable = 'off';
                    app.trial_lower_boundEditField.Editable = 'off';
                end
                
                if(strcmp(app.is_create_mnts_true,'false'))
                    app.trial_rangeEditField.Enable = 'off';
                    app.trial_rangeEditField.Editable = 'off';
                
                    app.wanted_eventsEditField.Enable = 'off';
                    app.wanted_eventsEditField.Editable = 'off';
                end
            end
            
            if(strcmp(app.is_create_mnts_true,'true'))
                app.trial_rangeEditField.Enable = 'on';
                app.trial_rangeEditField.Editable = 'on';
                
                app.wanted_eventsEditField.Enable = 'on';
                app.wanted_eventsEditField.Editable = 'on';
            else
                if(strcmp(app.is_format_psth_true,'false'))
                    app.trial_rangeEditField.Enable = 'off';
                    app.trial_rangeEditField.Editable = 'off';
                
                    app.wanted_eventsEditField.Enable = 'off';
                    app.wanted_eventsEditField.Editable = 'off';
                end
            end
            
            if(strcmp(app.is_pc_analysis_true,'true'))
                app.feature_filterDropDown.Enable = 'on';
                
                app.feature_valueEditField.Enable = 'on';
                app.feature_valueEditField.Editable = 'on';
            else
                app.feature_filterDropDown.Enable = 'off';
                
                app.feature_valueEditField.Enable = 'off';
                app.feature_valueEditField.Editable = 'off';
            end
            
            if(strcmp(app.is_ic_analysis_true,'true'))
                app.ic_pcEditField.Enable = 'on';
                app.ic_pcEditField.Editable = 'on';
                
                app.extendedEditField.Enable = 'on';
                app.extendedEditField.Editable = 'on';
                
                app.spheringDropDown.Enable = 'on';
                
                app.annealEditField.Enable = 'on';
                app.annealEditField.Editable = 'on';
                
                app.anneal_degEditField.Enable = 'on';
                app.anneal_degEditField.Editable = 'on';
                
                app.stopEditField.Enable = 'on';
                app.stopEditField.Editable = 'on';
                
                app.max_stepsEditField.Enable = 'on';
                app.max_stepsEditField.Editable = 'on';
                
                app.biasDropDown.Enable = 'on';
                
                app.momentumEditField.Enable = 'on';
                app.momentumEditField.Editable = 'on';
                
                app.rnd_resetDropDown.Enable = 'on';
                
                app.verboseEditField.Enable = 'on';
                app.verboseEditField.Editable = 'on';
            else
                app.ic_pcEditField.Enable = 'off';
                app.ic_pcEditField.Editable = 'off';
                
                app.extendedEditField.Enable = 'off';
                app.extendedEditField.Editable = 'off';
                
                app.spheringDropDown.Enable = 'off';
                
                app.annealEditField.Enable = 'off';
                app.annealEditField.Editable = 'off';
                
                app.anneal_degEditField.Enable = 'off';
                app.anneal_degEditField.Editable = 'off';
                
                app.stopEditField.Enable = 'off';
                app.stopEditField.Editable = 'off';
                
                app.max_stepsEditField.Enable = 'off';
                app.max_stepsEditField.Editable = 'off';
                
                app.biasDropDown.Enable = 'off';
                
                app.momentumEditField.Enable = 'off';
                app.momentumEditField.Editable = 'off';
                
                app.rnd_resetDropDown.Enable = 'off';
                
                app.verboseEditField.Enable = 'off';
                app.verboseEditField.Editable = 'off';
            end
            
            if(strcmp(app.is_rf_analysis_true,'true'))
                app.spanEditField.Enable = 'on';
                app.spanEditField.Editable = 'on';
                
                app.threshold_scaleEditField.Enable = 'on';
                app.threshold_scaleEditField.Editable = 'on';
                
                app.sig_binsEditField.Enable = 'on';
                app.sig_binsEditField.Editable = 'on';
                
                app.sig_checkEditField.Enable = 'on';
                app.sig_checkEditField.Editable = 'on';
            else
                app.spanEditField.Enable = 'off';
                app.spanEditField.Editable = 'off';
                
                app.threshold_scaleEditField.Enable = 'off';
                app.threshold_scaleEditField.Editable = 'off';
                
                app.sig_binsEditField.Enable = 'off';
                app.sig_binsEditField.Editable = 'off';
                
                app.sig_checkEditField.Enable = 'off';
                app.sig_checkEditField.Editable = 'off';
            end
            
            if(strcmp(app.is_make_psth_graphs_true,'true'))
                app.make_region_subplotDropDown.Enable = 'on';
                
                app.sub_columnsEditField.Enable = 'on';
                app.sub_columnsEditField.Editable = 'on';  
                
                app.sub_rowsEditField.Enable = 'on';
                app.sub_rowsEditField.Editable = 'on';
            else
                app.make_region_subplotDropDown.Enable = 'off';
                
                app.sub_columnsEditField.Enable = 'off';
                app.sub_columnsEditField.Editable = 'off';  
                
                app.sub_rowsEditField.Enable = 'off';
                app.sub_rowsEditField.Editable = 'off';
            end
            
            if(strcmp(app.is_nv_analysis_true,'true'))
                app.epsilonEditField.Enable = 'on';
                app.epsilonEditField.Editable = 'on';
                
                app.norm_var_scalingEditField.Enable = 'on';
                app.norm_var_scalingEditField.Editable = 'on';
                
                app.separate_eventsDropDown.Enable = 'on';
            else
                app.epsilonEditField.Enable = 'off';
                app.epsilonEditField.Editable = 'off';
                
                app.norm_var_scalingEditField.Enable = 'off';
                app.norm_var_scalingEditField.Editable = 'off';
                
                app.separate_eventsDropDown.Enable = 'off';
            end
            
            if(strcmp(app.is_psth_classify_true,'true'))
                app.bootstrap_classifierDropDown.Enable = 'on';
                
                app.boot_iterationsEditField.Enable = 'on';
                app.boot_iterationsEditField.Editable = 'on';
            else
                app.bootstrap_classifierDropDown.Enable = 'off';
                
                app.boot_iterationsEditField.Enable = 'off';
                app.boot_iterationsEditField.Editable = 'off';
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CompileButton
        function CompileButtonPushed(app, event)
            %%% Internal variables %%%
            goBackField = false;
            
            %%% All variable values from UI %%%%
            parse_files = app.parse_filesDropDown.Value;
            label_channels = app.label_channelsDropDown.Value;
            create_psth = app.create_psthDropDown.Value;
            rf_analysis = app.rf_analysisDropDown.Value;
            make_psth_graphs = app.make_psth_graphsDropDown.Value;
            nv_analysis = app.nv_analysisDropDown.Value;
            info_analysis = app.info_analysisDropDown.Value;
            psth_classify = app.psth_classifyDropDown.Value;
            create_mnts = app.create_mntsDropDown.Value;
            pc_analysis = app.pc_analysisDropDown.Value;
            ic_analysis = app.ic_analysisDropDown.Value;
            bin_size = app.bin_sizeEditField.Value;
            pre_time = app.pre_timeEditField.Value;
            post_time = app.post_timeEditField.Value;
            ignore_animal = app.ignore_animalDropDown.Value;
            total_trials = app.total_trialsEditField.Value;
            trial_lower_bound = app.trial_lower_boundEditField.Value;
            total_events = app.total_eventsEditField.Value;
            is_non_strobed_and_strobed = app.is_non_strobed_and_strobedDropDown.Value;
            event_map = app.event_mapEditField.Value;
            trial_range = app.trial_rangeEditField.Value;
            wanted_events = app.wanted_eventsEditField.Value;
            span = app.spanEditField.Value;
            threshold_scale = app.threshold_scaleEditField.Value;
            sig_bins = app.sig_binsEditField.Value;
            sig_check = app.sig_checkEditField.Value;
            make_region_subplot = app.make_region_subplotDropDown.Value;
            sub_columns = app.sub_columnsEditField.Value;
            sub_rows = app.sub_rowsEditField.Value;
            epsilon = app.epsilonEditField.Value;
            norm_var_scaling = app.norm_var_scalingEditField.Value;
            separate_events = app.separate_eventsDropDown.Value;
            bootstrap_classifier = app.bootstrap_classifierDropDown.Value;
            boot_iterations = app.boot_iterationsEditField.Value;
            feature_filter = app.feature_filterDropDown.Value;
            feature_value = app.feature_valueEditField.Value;
            ic_pc = app.ic_pcEditField.Value;
            extended = app.extendedEditField.Value;
            sphering = app.spheringDropDown.Value;
            anneal = app.annealEditField.Value;
            anneal_deg = app.anneal_degEditField.Value;
            stop = app.stopEditField.Value;
            max_steps = app.max_stepsEditField.Value;
            bias = app.biasDropDown.Value;
            momentum = app.momentumEditField.Value;
            rnd_reset = app.rnd_resetDropDown.Value;
            verbose = app.verboseEditField.Value;
            
            
            %this is for fields with array of numerical values
            if event_map ~= ""
                event_map = erase(event_map,{'[',']'});
                event_map = strrep(event_map,' ',',');
            end
            
            if trial_range ~= ""
                trial_range = erase(trial_range,{'[',']'});
                trial_range = strrep(trial_range,' ',',');
            end
            
            if wanted_events ~= ""
                wanted_events = erase(wanted_events,{'[',']'});
                wanted_events = strrep(wanted_events,' ',',');
            end
            
            % Put variable names in here
            variables = ["key" "parse_files" "label_channels" "create_psth" "rf_analysis" "make_psth_graphs" "nv_analysis" "info_analysis" ...
                "psth_classify" "create_mnts" "pc_analysis" "ic_analysis" "bin_size" "pre_time" "post_time" "ignore_animal"];
            
            % Put variable values in here
            values = ["value",string(parse_files),label_channels,create_psth,rf_analysis,make_psth_graphs,nv_analysis,info_analysis,psth_classify ...
                ,create_mnts,pc_analysis,ic_analysis,bin_size,pre_time,post_time,ignore_animal];
            
            % These are for variables that could be not required
           
            if strcmp(app.total_trialsEditField.Enable,'on')
                variables = [variables "total_trials"];
                values = [values,total_trials];
            end
            
            if strcmp(app.trial_lower_boundEditField.Enable,'on')
                variables = [variables "trial_lower_bound"];
                values = [values,trial_lower_bound];
            end
            
            if strcmp(app.total_eventsEditField.Enable,'on')
                variables = [variables "total_events"];
                values = [values,total_events];
            end
            
            if strcmp(app.is_non_strobed_and_strobedDropDown.Enable,'on')
                variables = [variables "is_non_strobed_and_strobed"];
                values = [values,is_non_strobed_and_strobed];
            end
            
            if strcmp(app.event_mapEditField.Enable,'on')
                variables = [variables "event_map"];
                values = [values,event_map];
            end
            
            if strcmp(app.trial_rangeEditField.Enable,'on')
                variables = [variables "trial_range"];
                values = [values,trial_range];
            end
            
            if strcmp(app.wanted_eventsEditField.Enable,'on')
                variables = [variables "wanted_events"];
                values = [values,wanted_events];
            end
            
            if strcmp(app.spanEditField.Enable,'on')
                variables = [variables "span"];
                values = [values,span];
            end
            
            if strcmp(app.threshold_scaleEditField.Enable,'on')
                variables = [variables "threshold_scale"];
                values = [values,threshold_scale];
            end
            
            if strcmp(app.sig_binsEditField.Enable,'on')
                variables = [variables "sig_bins"];
                values = [values,sig_bins];
            end
            
            if strcmp(app.sig_checkEditField.Enable,'on')
                variables = [variables "sig_check"];
                values = [values,sig_check];
            end
            
            if strcmp(app.make_region_subplotDropDown.Enable,'on')
                variables = [variables "make_region_subplot"];
                values = [values,make_region_subplot];
            end
            
            if strcmp(app.sub_columnsEditField.Enable,'on')
                variables = [variables "sub_columns"];
                values = [values,sub_columns];
            end
            
            if strcmp(app.sub_rowsEditField.Enable,'on')
                variables = [variables "sub_rows"];
                values = [values,sub_rows];
            end

            if strcmp(app.epsilonEditField.Enable,'on')
                variables = [variables "epsilon"];
                values = [values,epsilon];
            end

            if strcmp(app.norm_var_scalingEditField.Enable,'on')
                variables = [variables "norm_var_scaling"];
                values = [values,norm_var_scaling];
            end

            if strcmp(app.separate_eventsDropDown.Enable,'on')
                variables = [variables "separate_events"];
                values = [values,separate_events];
            end

            if strcmp(app.bootstrap_classifierDropDown.Enable,'on')
                variables = [variables "bootstrap_classifier"];
                values = [values,bootstrap_classifier];
            end

            if strcmp(app.boot_iterationsEditField.Enable,'on')
                variables = [variables "boot_iterations"];
                values = [values,boot_iterations];
            end

            if strcmp(app.feature_filterDropDown.Enable,'on')
                variables = [variables "feature_filter"];
                values = [values,feature_filter];
            end
            
            if strcmp(app.feature_valueEditField.Enable,'on')
                variables = [variables "feature_value"];
                values = [values,feature_value];
            end
            
            if strcmp(app.ic_pcEditField.Enable,'on')
                variables = [variables "ic_pc"];
                values = [values,ic_pc];
            end
            
            if strcmp(app.extendedEditField.Enable,'on')
                variables = [variables "extended"];
                values = [values,extended];
            end
            
            if strcmp(app.spheringDropDown.Enable,'on')
                variables = [variables "sphering"];
                values = [values,sphering];
            end
            
            if strcmp(app.annealEditField.Enable,'on')
                variables = [variables "anneal"];
                values = [values,anneal];
            end
            
            if strcmp(app.anneal_degEditField.Enable,'on')
                variables = [variables "anneal_deg"];
                values = [values,anneal_deg];
            end
            
            if strcmp(app.stopEditField.Enable,'on')
                variables = [variables "stop"];
                values = [values,stop];
            end
            
            if strcmp(app.max_stepsEditField.Enable,'on')
                variables = [variables "max_steps"];
                values = [values,max_steps];
            end
            
            if strcmp(app.biasDropDown.Enable,'on')
                variables = [variables "bias"];
                values = [values,bias];
            end
            
            if strcmp(app.momentumEditField.Enable,'on')
                variables = [variables "momentum"];
                values = [values,momentum];
            end
            
            if strcmp(app.rnd_resetDropDown.Enable,'on')
                variables = [variables "rnd_reset"];
                values = [values,rnd_reset];
            end
            
            if strcmp(app.verboseEditField.Enable,'on')
                variables = [variables,"verbose"];
                values = [values,verbose];
            end
            
            % converts array so it can be processed as CSV
            T = array2table(values','RowNames',variables');
            
            % gets the file names
            original_path = uigetdir(pwd);
            animal_list = dir(string(original_path));
            animal_names = {animal_list([animal_list.isdir] == 1 & ~contains({animal_list.name}, '.')).name};
            animal_array_values = [];

            % creates new popup for choosing the file name
            fig = uifigure('Position',[334 328 300 300]);
            newArray = [];
            for c = 1:length(animal_names)
                animal_name = char(animal_names(c));
                newArray = [newArray, uicheckbox(fig, 'Text',animal_name,...
                  'Value', 0,...
                  'Position',[100 (280 - c*20) 200 20])];
            end
            
            uilabel(fig,'Text','What files to copy to?','Position',[80 275 400 32]);
            
            %%%%%%%ADD IN CANCEL BUTTON%%%%%%%%%%%%
            uibutton(fig,'push',...
               'Position',[85, 10, 100, 22],...
               'Text', 'Compile',...
               'ButtonPushedFcn', @(btn,event) plotButtonPushed());
           
            uibutton(fig,'push',...
               'Position',[85, 10, 100, 22],...
               'Text', 'Cancel',...
               'ButtonPushedFcn', @(btn,event) goBack());
           
            % button to be pressed after selecting which files to save
            % config to
            function plotButtonPushed()
                for index = 1:length(newArray)
                    animal_array_values = [animal_array_values, newArray(index).Value];
                end
                fig.delete;
            end
            
            function goBack()
                fig.delete;
                goBackField = true;
            end
            
            waitfor(fig);
            
            if (goBackField ~= true)
                % generation of the config files
                for c = 1:length(animal_names)
                    if(animal_array_values(c) == 1)
                        animal_name = char(animal_names(c));
                        a=cellstr(reshape(animal_name,3,[])');
                        configFileName = strcat(a(1),'_',a(2),'_config');
                        pathOfFile = strcat(original_path,'/',animal_name,'/');
                        nameForCSV = strcat(string(pathOfFile),string(configFileName),'.csv');
                        writetable(T,string(nameForCSV),'WriteRowNames',true,'WriteVariableNames',0) 
                    end
                end
                f = msgbox('Generated CSV File');
                waitfor(f);
                app.delete; 
            end
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
            uiconfirm(app.UIFigure,'Restore default settings?','Confirm Close',...
                        'CloseFcn',@(src,event)mycallback(app,src,event));
        end

        % Value changed function: parse_filesDropDown
        function parse_filesDropDownValueChanged(app, event)
            value = app.parse_filesDropDown.Value;
            if (value == "true")
                app.is_parser_file_true = 'true';
            else
                app.is_parser_file_true = 'false';
            end
            checkButtons(app);
        end

        % Value changed function: create_psthDropDown
        function create_psthDropDownValueChanged(app, event)
            value = app.create_psthDropDown.Value;
            if (value == "true")
                app.is_format_psth_true = 'true';
            else 
                app.is_format_psth_true = 'false';
            end
            checkButtons(app)
        end

        % Value changed function: create_mntsDropDown
        function create_mntsDropDownValueChanged(app, event)
            value = app.create_mntsDropDown.Value;
            if (value == "true")
                app.is_create_mnts_true = 'true';
            else 
                app.is_create_mnts_true = 'false';
            end
            checkButtons(app)
        end

        % Value changed function: pc_analysisDropDown
        function pc_analysisDropDownValueChanged(app, event)
            value = app.pc_analysisDropDown.Value;
            if (value == "true")
                app.is_pc_analysis_true = 'true';
            else 
                app.is_pc_analysis_true = 'false';
            end
            checkButtons(app)
        end

        % Value changed function: ic_analysisDropDown
        function ic_analysisDropDownValueChanged(app, event)
            value = app.ic_analysisDropDown.Value;
            if (value == "true")
                app.is_ic_analysis_true = 'true';
            else 
                app.is_ic_analysis_true = 'false';
            end
            checkButtons(app)
        end

        % Value changed function: rf_analysisDropDown
        function rf_analysisDropDownValueChanged(app, event)
            value = app.rf_analysisDropDown.Value;
            if (value == "true")
                app.is_rf_analysis_true = 'true';
            else 
                app.is_rf_analysis_true = 'false';
            end
            checkButtons(app)
        end

        % Value changed function: make_psth_graphsDropDown
        function make_psth_graphsDropDownValueChanged(app, event)
            value = app.make_psth_graphsDropDown.Value;
            if (value == "true")
                app.is_make_psth_graphs_true = 'true';
            else 
                app.is_make_psth_graphs_true = 'false';
            end
            checkButtons(app)
        end

        % Value changed function: nv_analysisDropDown
        function nv_analysisDropDownValueChanged(app, event)
            value = app.nv_analysisDropDown.Value;
            if (value == "true")
                app.is_nv_analysis_true = 'true';
            else 
                app.is_nv_analysis_true = 'false';
            end
            checkButtons(app)
        end

        % Value changed function: psth_classifyDropDown
        function psth_classifyDropDownValueChanged(app, event)
            value = app.psth_classifyDropDown.Value;
            if (value == "true")
                app.is_psth_classify_true = 'true';
            else 
                app.is_psth_classify_true = 'false';
            end
            checkButtons(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.CompileButton = uibutton(app.UIFigure, 'push');
            app.CompileButton.ButtonPushedFcn = createCallbackFcn(app, @CompileButtonPushed, true);
            app.CompileButton.Text = 'Compile';
            app.DefaultButton = uibutton(app.UIFigure, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Tooltip = {'restore values to default values'};
            app.DefaultButton.Text = 'Default';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RequiredTab = uitab(app.TabGroup);
            app.RequiredTab.Title = 'Required';
            app.GridLayout = uigridlayout(app.RequiredTab);
            app.parse_filesDropDownLabel = uilabel(app.GridLayout);
            app.parse_filesDropDownLabel.Text = 'parse_files';
            app.parse_filesDropDown = uidropdown(app.GridLayout);
            app.parse_filesDropDown.Items = {'true', 'false'};
            app.parse_filesDropDown.ValueChangedFcn = createCallbackFcn(app, @parse_filesDropDownValueChanged, true);
            app.parse_filesDropDown.Tooltip = {'Controls if .plx/.pl2 files are parsed'};
            app.parse_filesDropDown.Value = 'true';
            app.label_channelsDropDownLabel = uilabel(app.GridLayout);
            app.label_channelsDropDownLabel.Text = 'label_channels';
            app.label_channelsDropDown = uidropdown(app.GridLayout);
            app.label_channelsDropDown.Items = {'true', 'false'};
            app.label_channelsDropDown.Tooltip = {'Label neurons according to labels csv'};
            app.label_channelsDropDown.Value = 'true';
            app.create_psthDropDownLabel = uilabel(app.GridLayout);
            app.create_psthDropDownLabel.Text = 'create_psth';
            app.create_psthDropDown = uidropdown(app.GridLayout);
            app.create_psthDropDown.Items = {'true', 'false'};
            app.create_psthDropDown.ValueChangedFcn = createCallbackFcn(app, @create_psthDropDownValueChanged, true);
            app.create_psthDropDown.Tooltip = {'Format PSTH from parsed data'};
            app.create_psthDropDown.Value = 'true';
            app.rf_analysisDropDownLabel = uilabel(app.GridLayout);
            app.rf_analysisDropDownLabel.Text = 'rf_analysis';
            app.rf_analysisDropDown = uidropdown(app.GridLayout);
            app.rf_analysisDropDown.Items = {'true', 'false'};
            app.rf_analysisDropDown.ValueChangedFcn = createCallbackFcn(app, @rf_analysisDropDownValueChanged, true);
            app.rf_analysisDropDown.Tooltip = {'Run receptive field analysis'};
            app.rf_analysisDropDown.Value = 'true';
            app.make_psth_graphsDropDownLabel = uilabel(app.GridLayout);
            app.make_psth_graphsDropDownLabel.Text = 'make_psth_graphs';
            app.make_psth_graphsDropDown = uidropdown(app.GridLayout);
            app.make_psth_graphsDropDown.Items = {'true', 'false'};
            app.make_psth_graphsDropDown.ValueChangedFcn = createCallbackFcn(app, @make_psth_graphsDropDownValueChanged, true);
            app.make_psth_graphsDropDown.Tooltip = {'Create PSTH graphs'};
            app.make_psth_graphsDropDown.Value = 'true';
            app.nv_analysisDropDownLabel = uilabel(app.GridLayout);
            app.nv_analysisDropDownLabel.Text = 'nv_analysis';
            app.nv_analysisDropDown = uidropdown(app.GridLayout);
            app.nv_analysisDropDown.Items = {'true', 'false'};
            app.nv_analysisDropDown.ValueChangedFcn = createCallbackFcn(app, @nv_analysisDropDownValueChanged, true);
            app.nv_analysisDropDown.Tooltip = {'Run normalized variance analysis'};
            app.nv_analysisDropDown.Value = 'true';
            app.info_analysisDropDownLabel = uilabel(app.GridLayout);
            app.info_analysisDropDownLabel.Text = 'info_analysis';
            app.info_analysisDropDown = uidropdown(app.GridLayout);
            app.info_analysisDropDown.Items = {'true', 'false'};
            app.info_analysisDropDown.Tooltip = {'Run mutual info analysis'};
            app.info_analysisDropDown.Value = 'true';
            app.psth_classifyDropDownLabel = uilabel(app.GridLayout);
            app.psth_classifyDropDownLabel.Text = 'psth_classify';
            app.psth_classifyDropDown = uidropdown(app.GridLayout);
            app.psth_classifyDropDown.Items = {'true', 'false'};
            app.psth_classifyDropDown.ValueChangedFcn = createCallbackFcn(app, @psth_classifyDropDownValueChanged, true);
            app.psth_classifyDropDown.Tooltip = {'Run psth classification'};
            app.psth_classifyDropDown.Value = 'true';
            app.create_mntsDropDownLabel = uilabel(app.GridLayout);
            app.create_mntsDropDownLabel.Text = 'create_mnts';
            app.create_mntsDropDown = uidropdown(app.GridLayout);
            app.create_mntsDropDown.Items = {'true', 'false'};
            app.create_mntsDropDown.ValueChangedFcn = createCallbackFcn(app, @create_mntsDropDownValueChanged, true);
            app.create_mntsDropDown.Tooltip = {'Run normalized variance analysis'};
            app.create_mntsDropDown.Value = 'true';
            app.pc_analysisDropDownLabel = uilabel(app.GridLayout);
            app.pc_analysisDropDownLabel.Text = 'pc_analysis';
            app.pc_analysisDropDown = uidropdown(app.GridLayout);
            app.pc_analysisDropDown.Items = {'true', 'false'};
            app.pc_analysisDropDown.ValueChangedFcn = createCallbackFcn(app, @pc_analysisDropDownValueChanged, true);
            app.pc_analysisDropDown.Tooltip = {'Run mutual info analysis'};
            app.pc_analysisDropDown.Value = 'true';
            app.ic_analysisDropDownLabel = uilabel(app.GridLayout);
            app.ic_analysisDropDownLabel.Text = 'ic_analysis';
            app.ic_analysisDropDown = uidropdown(app.GridLayout);
            app.ic_analysisDropDown.Items = {'true', 'false'};
            app.ic_analysisDropDown.ValueChangedFcn = createCallbackFcn(app, @ic_analysisDropDownValueChanged, true);
            app.ic_analysisDropDown.Tooltip = {'Run psth classification'};
            app.ic_analysisDropDown.Value = 'true';
            app.bin_sizeEditFieldLabel = uilabel(app.GridLayout);
            app.bin_sizeEditFieldLabel.Text = 'bin_size';
            app.bin_sizeEditField = uieditfield(app.GridLayout, 'numeric');
            app.bin_sizeEditField.Tooltip = {'Bin size to make histogram'};
            app.bin_sizeEditField.Value = 0.005;
            app.pre_timeEditFieldLabel = uilabel(app.GridLayout);
            app.pre_timeEditFieldLabel.Text = 'pre_time';
            app.pre_timeEditField = uieditfield(app.GridLayout, 'numeric');
            app.pre_timeEditField.Tooltip = {'Pre time window before event onset'};
            app.pre_timeEditField.Value = 0.1;
            app.post_timeEditFieldLabel = uilabel(app.GridLayout);
            app.post_timeEditFieldLabel.Text = 'post_time';
            app.post_timeEditField = uieditfield(app.GridLayout, 'numeric');
            app.post_timeEditField.Tooltip = {'Post time window after event onset'};
            app.post_timeEditField.Value = 0.2;
            app.ignore_animalDropDownLabel = uilabel(app.GridLayout);
            app.ignore_animalDropDownLabel.Text = 'ignore_animal';
            app.ignore_animalDropDown = uidropdown(app.GridLayout);
            app.ignore_animalDropDown.Items = {'true', 'false'};
            app.ignore_animalDropDown.Tooltip = {'Skip animal'};
            app.ignore_animalDropDown.Value = 'true';
            app.ParserTab = uitab(app.TabGroup);
            app.ParserTab.Title = 'Parser';
            app.GridLayout2 = uigridlayout(app.ParserTab);
            app.total_trialsEditFieldLabel = uilabel(app.GridLayout2);
            app.total_trialsEditFieldLabel.Text = 'total_trials';
            app.total_trialsEditField = uieditfield(app.GridLayout2, 'numeric');
            app.total_trialsEditField.Tooltip = {'Total trials for each event'};
            app.total_trialsEditField.Value = 100;
            app.trial_lower_boundEditFieldLabel = uilabel(app.GridLayout2);
            app.trial_lower_boundEditFieldLabel.Text = 'trial_lower_bound';
            app.trial_lower_boundEditField = uieditfield(app.GridLayout2, 'numeric');
            app.trial_lower_boundEditField.Tooltip = {'Lower limit of expected trials for each event'};
            app.trial_lower_boundEditField.Value = 50;
            app.total_eventsEditFieldLabel = uilabel(app.GridLayout2);
            app.total_eventsEditFieldLabel.Text = 'total_events';
            app.total_eventsEditField = uieditfield(app.GridLayout2, 'numeric');
            app.total_eventsEditField.Tooltip = {'How many events are there'};
            app.total_eventsEditField.Value = 4;
            app.is_non_strobed_and_strobedDropDown = uidropdown(app.GridLayout2);
            app.is_non_strobed_and_strobedDropDown.Items = {'true', 'false'};
            app.is_non_strobed_and_strobedDropDown.Tooltip = {'Recordings is a mix of strobed and non strobed'};
            app.is_non_strobed_and_strobedDropDown.Value = 'true';
            app.is_non_strobed_and_strobedDropDownLabel = uilabel(app.GridLayout2);
            app.is_non_strobed_and_strobedDropDownLabel.Text = 'is_non_strobed_and_strobed';
            app.event_mapEditFieldLabel = uilabel(app.GridLayout2);
            app.event_mapEditFieldLabel.Text = 'event_map';
            app.event_mapEditField = uieditfield(app.GridLayout2, 'text');
            app.event_mapEditField.Tooltip = {'Maps strobed to non strobed. Can be left empty.'; ''; 'ex. [1 2 3 6]'};
            app.PSTHandMNTSTab = uitab(app.TabGroup);
            app.PSTHandMNTSTab.Title = 'PSTH and MNTS';
            app.GridLayout3 = uigridlayout(app.PSTHandMNTSTab);
            app.trial_rangeEditFieldLabel = uilabel(app.GridLayout3);
            app.trial_rangeEditFieldLabel.Text = 'trial_range';
            app.trial_rangeEditField = uieditfield(app.GridLayout3, 'text');
            app.trial_rangeEditField.Tooltip = {'Inclusive range for trials. When empty'; ' takes all trials.'; ''; 'ex. [1 300]'};
            app.wanted_eventsEditFieldLabel = uilabel(app.GridLayout3);
            app.wanted_eventsEditFieldLabel.Text = 'wanted_events';
            app.wanted_eventsEditField = uieditfield(app.GridLayout3, 'text');
            app.wanted_eventsEditField.Tooltip = {'Controls which events are used. If empty'; ' uses all events.'; ''; 'ex. [1 3 4]'};
            app.PCATab = uitab(app.TabGroup);
            app.PCATab.Title = 'PCA';
            app.GridLayout4 = uigridlayout(app.PCATab);
            app.feature_filterDropDownLabel = uilabel(app.GridLayout4);
            app.feature_filterDropDownLabel.Text = 'feature_filter';
            app.feature_filterDropDown = uidropdown(app.GridLayout4);
            app.feature_filterDropDown.Items = {'all', 'percent_var', 'pcs'};
            app.feature_filterDropDown.Tooltip = {'''all'': uses all pcs'; '''percent_var'': pc selection based on % variance explained'; '''pcs'': first x components'};
            app.feature_filterDropDown.Value = 'all';
            app.feature_valueEditFieldLabel = uilabel(app.GridLayout4);
            app.feature_valueEditFieldLabel.Text = 'feature_value';
            app.feature_valueEditField = uieditfield(app.GridLayout4, 'numeric');
            app.feature_valueEditField.Tooltip = {'''all'': 0'; '''percent_var'': 1-100%'; '''pcs'': # of pcs'};
            app.feature_valueEditField.Value = 4;
            app.ICATab = uitab(app.TabGroup);
            app.ICATab.Title = 'ICA';
            app.GridLayout5 = uigridlayout(app.ICATab);
            app.ic_pcEditFieldLabel = uilabel(app.GridLayout5);
            app.ic_pcEditFieldLabel.Text = 'ic_pc';
            app.ic_pcEditField = uieditfield(app.GridLayout5, 'numeric');
            app.ic_pcEditField.Tooltip = {'# pcs used for ICA'; 'default = 0'};
            app.ic_pcEditField.Value = 3;
            app.extendedEditFieldLabel = uilabel(app.GridLayout5);
            app.extendedEditFieldLabel.Text = 'extended';
            app.extendedEditField = uieditfield(app.GridLayout5, 'numeric');
            app.extendedEditField.Tooltip = {'# blocks for extended-ica'};
            app.extendedEditField.Value = 3;
            app.spheringDropDownLabel = uilabel(app.GridLayout5);
            app.spheringDropDownLabel.Text = 'sphering';
            app.spheringDropDown = uidropdown(app.GridLayout5);
            app.spheringDropDown.Items = {'on', 'off'};
            app.spheringDropDown.Tooltip = {'''on'': spheres data'; '''off'': doesnt sphere data'; 'default = ''on'''};
            app.spheringDropDown.Value = 'on';
            app.annealEditFieldLabel = uilabel(app.GridLayout5);
            app.annealEditFieldLabel.Text = 'anneal';
            app.annealEditField = uieditfield(app.GridLayout5, 'text');
            app.annealEditField.Tooltip = {'controls speed of convergence. Can set anneal to default for defaults. (0'; ' 1]'; 'default: .9'; 'if extended: .98'};
            app.anneal_degEditFieldLabel = uilabel(app.GridLayout5);
            app.anneal_degEditFieldLabel.Text = 'anneal_deg';
            app.anneal_degEditField = uieditfield(app.GridLayout5, 'numeric');
            app.anneal_degEditField.Tooltip = {'Weight change for anneal'; 'default = 70'};
            app.anneal_degEditField.Value = 3;
            app.stopEditFieldLabel = uilabel(app.GridLayout5);
            app.stopEditFieldLabel.Text = 'stop';
            app.stopEditField = uieditfield(app.GridLayout5, 'text');
            app.stopEditField.Tooltip = {'Stops training when weight change is less than stop. Can set stop to default for default action.'; 'default = 1e-6'; 'tot channels < 33'; 'default = 1e-7'};
            app.max_stepsEditFieldLabel = uilabel(app.GridLayout5);
            app.max_stepsEditFieldLabel.Text = 'max_steps';
            app.max_stepsEditField = uieditfield(app.GridLayout5, 'numeric');
            app.max_stepsEditField.Tooltip = {'Max training steps'; 'default = 512'};
            app.max_stepsEditField.Value = 3;
            app.biasDropDownLabel = uilabel(app.GridLayout5);
            app.biasDropDownLabel.Text = 'bias';
            app.biasDropDown = uidropdown(app.GridLayout5);
            app.biasDropDown.Items = {'on', 'off'};
            app.biasDropDown.Tooltip = {'bias adjustment'; 'default = ''on'''};
            app.biasDropDown.Value = 'on';
            app.momentumEditFieldLabel = uilabel(app.GridLayout5);
            app.momentumEditFieldLabel.Text = 'momentum';
            app.momentumEditField = uieditfield(app.GridLayout5, 'numeric');
            app.momentumEditField.Tooltip = {'Training momentum [0<#<1]'; 'default = 0'};
            app.momentumEditField.Value = 3;
            app.rnd_resetDropDown = uidropdown(app.GridLayout5);
            app.rnd_resetDropDown.Items = {'true', 'false'};
            app.rnd_resetDropDown.Tooltip = {'Control if random seed is reset to return same decmposition.'; 'default = false'};
            app.rnd_resetDropDown.Value = 'true';
            app.rnd_resetDropDownLabel = uilabel(app.GridLayout5);
            app.rnd_resetDropDownLabel.Text = 'rnd_reset';
            app.verboseEditFieldLabel = uilabel(app.GridLayout5);
            app.verboseEditFieldLabel.Text = 'verbose';
            app.verboseEditField = uieditfield(app.GridLayout5, 'text');
            app.verboseEditField.Tooltip = {'Give detailed messages in terminal.'; 'default = ''off'''};
            app.ReceptiveFieldAnalysisTab = uitab(app.TabGroup);
            app.ReceptiveFieldAnalysisTab.Title = 'Receptive Field Analysis';
            app.GridLayout6 = uigridlayout(app.ReceptiveFieldAnalysisTab);
            app.spanEditFieldLabel = uilabel(app.GridLayout6);
            app.spanEditFieldLabel.Text = 'span';
            app.spanEditField = uieditfield(app.GridLayout6, 'numeric');
            app.spanEditField.Tooltip = {'How many bins smoothed when finding significant response'};
            app.spanEditField.Value = 3;
            app.threshold_scaleEditFieldLabel = uilabel(app.GridLayout6);
            app.threshold_scaleEditFieldLabel.Text = 'threshold_scale';
            app.threshold_scaleEditField = uieditfield(app.GridLayout6, 'numeric');
            app.threshold_scaleEditField.Tooltip = {'Scales background firing rate standard deviation for threshold'};
            app.threshold_scaleEditField.Value = 1.65;
            app.sig_binsEditFieldLabel = uilabel(app.GridLayout6);
            app.sig_binsEditFieldLabel.Text = 'sig_bins';
            app.sig_binsEditField = uieditfield(app.GridLayout6, 'numeric');
            app.sig_binsEditField.Tooltip = {'# consecutive bins needed above threshold for significance'};
            app.sig_binsEditField.Value = 3;
            app.sig_checkEditFieldLabel = uilabel(app.GridLayout6);
            app.sig_checkEditFieldLabel.Text = 'sig_check';
            app.sig_checkEditField = uieditfield(app.GridLayout6, 'numeric');
            app.sig_checkEditField.Tooltip = {'Control statistical check for significant response'};
            app.sig_checkEditField.Value = 1;
            app.PSTHGraphingTab = uitab(app.TabGroup);
            app.PSTHGraphingTab.Title = 'PSTH Graphing';
            app.GridLayout7 = uigridlayout(app.PSTHGraphingTab);
            app.make_region_subplotDropDownLabel = uilabel(app.GridLayout7);
            app.make_region_subplotDropDownLabel.Text = 'make_region_subplot';
            app.make_region_subplotDropDown = uidropdown(app.GridLayout7);
            app.make_region_subplotDropDown.Items = {'true', 'false'};
            app.make_region_subplotDropDown.Tooltip = {'Plot all units on single .fig'};
            app.make_region_subplotDropDown.Value = 'true';
            app.sub_columnsEditFieldLabel = uilabel(app.GridLayout7);
            app.sub_columnsEditFieldLabel.Text = 'sub_columns';
            app.sub_columnsEditField = uieditfield(app.GridLayout7, 'numeric');
            app.sub_columnsEditField.Tooltip = {'How many PSTHs plotted on row.'};
            app.sub_columnsEditField.Value = 50;
            app.sub_rowsEditFieldLabel = uilabel(app.GridLayout7);
            app.sub_rowsEditFieldLabel.Text = 'sub_rows';
            app.sub_rowsEditField = uieditfield(app.GridLayout7, 'numeric');
            app.sub_rowsEditField.Tooltip = {'How many PSTHs plotted on row.'};
            app.sub_rowsEditField.Value = 50;
            app.NormalizedVarianceAnalysisTab = uitab(app.TabGroup);
            app.NormalizedVarianceAnalysisTab.Title = 'Normalized Variance Analysis';
            app.GridLayout8 = uigridlayout(app.NormalizedVarianceAnalysisTab);
            app.epsilonEditFieldLabel = uilabel(app.GridLayout8);
            app.epsilonEditFieldLabel.Text = 'epsilon';
            app.epsilonEditField = uieditfield(app.GridLayout8, 'numeric');
            app.epsilonEditField.Tooltip = {'Constant used to prevent division by zero'};
            app.epsilonEditField.Value = 0.01;
            app.norm_var_scalingEditFieldLabel = uilabel(app.GridLayout8);
            app.norm_var_scalingEditFieldLabel.Text = 'norm_var_scaling';
            app.norm_var_scalingEditField = uieditfield(app.GridLayout8, 'numeric');
            app.norm_var_scalingEditField.Tooltip = {'Scale the normalized variance'};
            app.norm_var_scalingEditField.Value = 0.2;
            app.separate_eventsDropDownLabel = uilabel(app.GridLayout8);
            app.separate_eventsDropDownLabel.Text = 'separate_events';
            app.separate_eventsDropDown = uidropdown(app.GridLayout8);
            app.separate_eventsDropDown.Items = {'true', 'false'};
            app.separate_eventsDropDown.Tooltip = {'Separate event trials or combine all trials'};
            app.separate_eventsDropDown.Value = 'true';
            app.PSTHClassifierTab = uitab(app.TabGroup);
            app.PSTHClassifierTab.Title = 'PSTH Classifier';
            app.GridLayout9 = uigridlayout(app.PSTHClassifierTab);
            app.bootstrap_classifierDropDownLabel = uilabel(app.GridLayout9);
            app.bootstrap_classifierDropDownLabel.Text = 'bootstrap_classifier';
            app.bootstrap_classifierDropDown = uidropdown(app.GridLayout9);
            app.bootstrap_classifierDropDown.Items = {'true', 'false'};
            app.bootstrap_classifierDropDown.Tooltip = {'Find random info via bootstrapping'};
            app.bootstrap_classifierDropDown.Value = 'true';
            app.boot_iterationsEditFieldLabel = uilabel(app.GridLayout9);
            app.boot_iterationsEditFieldLabel.Text = 'boot_iterations';
            app.boot_iterationsEditField = uieditfield(app.GridLayout9, 'numeric');
            app.boot_iterationsEditField.Tooltip = {'How many iterations used to find avg random info'};
            app.boot_iterationsEditField.Value = 50;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConfigCSVGenerator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end