classdef LabelCSVGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        LoadFileButton                  matlab.ui.control.Button
        FileNameLabel                   matlab.ui.control.Label
        FileNameDisplay                 matlab.ui.control.Label
        LabelPanel                      matlab.ui.container.Panel
        UITable                         matlab.ui.control.Table
        SelectAllButton                 matlab.ui.control.Button
        SelectUnselectedButton          matlab.ui.control.Button
        DeselectAllButton               matlab.ui.control.Button
        SelectAllButton_2               matlab.ui.control.Button
        SelectUnselectedButton_2        matlab.ui.control.Button
        DeselectAllButton_2             matlab.ui.control.Button
        DeselectAllButton_3             matlab.ui.control.Button
        DeselectAllButton_4             matlab.ui.control.Button
        DeselectAllButton_5             matlab.ui.control.Button
        SelectUnselectedButton_3        matlab.ui.control.Button
        SelectAllButton_3               matlab.ui.control.Button
        SelectAllButton_4               matlab.ui.control.Button
        SelectAllButton_5               matlab.ui.control.Button
        SelectUnselectedButton_4        matlab.ui.control.Button
        SelectUnselectedButton_5        matlab.ui.control.Button
        ChannelEditFieldLabel           matlab.ui.control.Label
        ChannelEditField                matlab.ui.control.EditField
        SetChannelButton                matlab.ui.control.Button
        LabelEditFieldLabel             matlab.ui.control.Label
        LabelEditField                  matlab.ui.control.EditField
        LabelIDEditFieldLabel           matlab.ui.control.Label
        LabelIDEditField                matlab.ui.control.EditField
        RecordingSessionEditFieldLabel  matlab.ui.control.Label
        RecordingSessionEditField       matlab.ui.control.EditField
        SetLabelandLabelIDButton        matlab.ui.control.Button
        SetRecordingSessionButton       matlab.ui.control.Button
        DateEditFieldLabel              matlab.ui.control.Label
        DateEditField                   matlab.ui.control.EditField
        SetDateButton                   matlab.ui.control.Button
        NotesEditFieldLabel             matlab.ui.control.Label
        NotesEditField                  matlab.ui.control.EditField
        SetNotesButton                  matlab.ui.control.Button
        GenerateCSVPanel                matlab.ui.container.Panel
        CSVNameNoExtensionEditFieldLabel  matlab.ui.control.Label
        CSVNameNoExtensionEditField     matlab.ui.control.EditField
        GenerateCSVButton               matlab.ui.control.Button
        AppendtoExistingCSVButton       matlab.ui.control.Button
        ClearTableButton                matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
           %Retrieve file from directory as selected by user
            [File,Path] = uigetfile;
            [~, File_Name, Ext] = fileparts(File);
            app.FileNameDisplay.Text = File_Name;
            %Load the sig channel names for the different file types
            switch lower(Ext)
                case '.mat'
                    File_Path = strcat(Path, File);
                    load(File_Path,'neuron_map');
                    Sig_Channels = neuron_map(:,1);
                case '.plx' 
                    [~, Sig_Channels] = plx_chan_names(File);
                    Sig_Channels = cellstr(Sig_Channels);
                case '.pl2'
                    [~, Sig_Channels] = plx_chan_names(File);
                    Sig_Channels = cellstr(Sig_Channels);
                otherwise error('Unexpected file extension: %s', Ext);
            end
            %Set variables to generate a UI table and checkboxes
            Length = length(Sig_Channels);
                    Selection(:,1:Length)= false;
                    Region_Selection = Selection';
                    Region = repmat({''}, Length, 1);
                    Region_ID = repmat({''}, Length, 1);
                    Channel_Selection = Selection';
                    Channel = repmat({''}, Length, 1);
                    Rec_Session_Selection = Selection';
                    Rec_Session = repmat({''}, Length, 1);
                    Date_Selection = Selection';
                    Date = repmat({''}, Length, 1);
                    Notes_Selection = Selection';
                    Notes = repmat({''}, Length, 1);
                    app.UITable.Data = [cell2table(Sig_Channels), array2table(Channel_Selection), Channel, array2table(Region_Selection),Region,Region_ID, array2table(Rec_Session_Selection), Rec_Session,array2table(Date_Selection), Date, array2table(Notes_Selection), Notes];                
        end

        % Button pushed function: SetChannelButton
        function SetChannelButtonPushed(app, event)
          %Button is used to set channel name from input into text entry field
            L = size(app.UITable.Data, 1);
            if strcmp(app.ChannelEditField.Value, '')
               f = errordlg('Channel Name is Blank','Channel Error'); 
            else
                for i = 1:L
                    if table2array(app.UITable.Data(i,2)) == true
                            app.UITable.Data(i,3) = cellstr(app.ChannelEditField.Value);
                            app.UITable.Data(i,2) = array2table(false);
                    end
                end
            app.ChannelEditField.Value = '';   
            end
        end

        % Button pushed function: SetLabelandLabelIDButton
        function SetLabelandLabelIDButtonPushed(app, event)
            L = size(app.UITable.Data, 1);
            if strcmp(app.LabelEditField.Value, '') || strcmp(app.LabelIDEditField.Value, '')
                f = errordlg('Label/Label ID is Blank','Labeling Error');
            elseif isempty(str2num(app.LabelIDEditField.Value ))
                f = errordlg('LabelID is not Numerical Value', 'LabelID Error');
            else
                for i = 1:L
                    if table2array(app.UITable.Data(i,4)) == true
                       app.UITable.Data(i,5) = cellstr(app.LabelEditField.Value);
                       app.UITable.Data(i,6) = cellstr(app.LabelIDEditField.Value);
                       app.UITable.Data(i,4) = array2table(false);
                    end   
                end
            app.LabelEditField.Value = '';
            app.LabelIDEditField.Value = '';
            end
        end

        % Button pushed function: SetRecordingSessionButton
        function SetRecordingSessionButtonPushed(app, event)
            L = size(app.UITable.Data, 1);
            if strcmp(app.RecordingSessionEditField.Value, '')
                f = errordlg('Recording Session is Blank','Rec Session Error');
            elseif isempty(str2num(app.RecordingSessionEditField.Value ))
                f = errordlg('Recording Session is not Numerical Value', 'Rec Session Error');
            else
                for i = 1:L
                    if table2array(app.UITable.Data(i,7)) == true
                       app.UITable.Data(i,8) = cellstr(app.RecordingSessionEditField.Value);
                       app.UITable.Data(i,7) = array2table(false);
                    end
                end
            app.RecordingSessionEditField.Value = ''; 
            end
        end

        % Button pushed function: SetDateButton
        function SetDateButtonPushed(app, event)
            L = size(app.UITable.Data, 1);
            if strcmp(app.DateEditField.Value, '')
                f = errordlg('Date is Blank','Date Error');
            elseif isempty(str2num(app.DateEditField.Value ))
                f = errordlg('Date is not Numerical Value', 'Date Error');
            elseif strlength(app.DateEditField.Value) ~= 8
                f = errordlg('Date is not correct format', 'Date Error');
            else
                for i = 1:L
                    if table2array(app.UITable.Data(i,9)) == true
                       app.UITable.Data(i,10) = cellstr(app.DateEditField.Value);
                       app.UITable.Data(i,9) = array2table(false);
                    end
                end
            app.DateEditField.Value = ''; 
            end
        end

        % Button pushed function: SetNotesButton
        function SetNotesButtonPushed(app, event)
            L = size(app.UITable.Data, 1);
            if strcmp(app.NotesEditField.Value, '')
                f = errordlg('Notes is Blank','Notes Error');
            else
            for i = 1:L
                if table2array(app.UITable.Data(i,11)) == true
                   app.UITable.Data(i,12) = cellstr(app.NotesEditField.Value);
                   app.UITable.Data(i,11) = array2table(false);
                end
            end
            app.NotesEditField.Value = ''; 
            end
        end

        % Button pushed function: SelectAllButton
        function SelectAllButtonPushed(app, event)
           L = size(app.UITable.Data, 1);
            for i = 1:L
                 app.UITable.Data(i,2) = array2table(true);
            end
        end

        % Button pushed function: SelectAllButton_2
        function SelectAllButton_2Pushed(app, event)
           L = size(app.UITable.Data, 1);
            for i = 1:L
                 app.UITable.Data(i,4) = array2table(true);
            end 
        end

        % Button pushed function: SelectAllButton_3
        function SelectAllButton_3Pushed(app, event)
            L = size(app.UITable.Data, 1);
            for i = 1:L
                 app.UITable.Data(i,7) = array2table(true);
            end 
        end

        % Button pushed function: SelectAllButton_4
        function SelectAllButton_4Pushed(app, event)
            L = size(app.UITable.Data, 1);
            for i = 1:L
                 app.UITable.Data(i,9) = array2table(true);
            end 
        end

        % Button pushed function: SelectAllButton_5
        function SelectAllButton_5Pushed(app, event)
            L = size(app.UITable.Data, 1);
            for i = 1:L
                 app.UITable.Data(i,11) = array2table(true);
            end 
        end

        % Button pushed function: DeselectAllButton
        function DeselectAllButtonPushed(app, event)
            L = size(app.UITable.Data, 1);
            for i = 1:L
                 app.UITable.Data(i,2) = array2table(false);
            end
        end

        % Button pushed function: DeselectAllButton_2
        function DeselectAllButton_2Pushed(app, event)
            L = size(app.UITable.Data, 1);
            for i = 1:L
                 app.UITable.Data(i,4) = array2table(false);
            end
        end

        % Button pushed function: DeselectAllButton_3
        function DeselectAllButton_3Pushed(app, event)
            L = size(app.UITable.Data, 1);
            for i = 1:L
                 app.UITable.Data(i,7) = array2table(false);
            end
        end

        % Button pushed function: DeselectAllButton_4
        function DeselectAllButton_4Pushed(app, event)
            L = size(app.UITable.Data, 1);
            for i = 1:L
                 app.UITable.Data(i,9) = array2table(false);
            end
        end

        % Button pushed function: DeselectAllButton_5
        function DeselectAllButton_5Pushed(app, event)
            L = size(app.UITable.Data, 1);
            for i = 1:L
                 app.UITable.Data(i,11) = array2table(false);
            end
        end

        % Button pushed function: SelectUnselectedButton
        function SelectUnselectedButtonPushed(app, event)
            L = size(app.UITable.Data, 1);
            for i = 1:L
                if strcmp(cellstr(table2cell(app.UITable.Data(i,3))), '') == 1;
                    app.UITable.Data(i,2) = array2table(true);
                end
            end
        end

        % Button pushed function: SelectUnselectedButton_2
        function SelectUnselectedButton_2Pushed(app, event)
            L = size(app.UITable.Data, 1);
            for i = 1:L
                if strcmp(cellstr(table2cell(app.UITable.Data(i,5))), '') == 1;
                    app.UITable.Data(i,4) = array2table(true);
                end
            end
        end

        % Button pushed function: SelectUnselectedButton_3
        function SelectUnselectedButton_3Pushed(app, event)
            L = size(app.UITable.Data, 1);
            for i = 1:L
                if strcmp(cellstr(table2cell(app.UITable.Data(i,8))), '') == 1;
                    app.UITable.Data(i,7) = array2table(true);
                end
            end
        end

        % Button pushed function: SelectUnselectedButton_4
        function SelectUnselectedButton_4Pushed(app, event)
            L = size(app.UITable.Data, 1);
            for i = 1:L
                if strcmp(cellstr(table2cell(app.UITable.Data(i,10))), '') == 1;
                    app.UITable.Data(i,9) = array2table(true);
                end
            end
        end

        % Button pushed function: SelectUnselectedButton_5
        function SelectUnselectedButton_5Pushed(app, event)
            L = size(app.UITable.Data, 1);
            for i = 1:L
                if strcmp(cellstr(table2cell(app.UITable.Data(i,12))), '') == 1;
                    app.UITable.Data(i,11) = array2table(true);
                end
            end
        end

        % Button pushed function: AppendtoExistingCSVButton
        function AppendtoExistingCSVButtonPushed(app, event)
                Sig_Channels = table2array(app.UITable.Data(:,1));
                Label = table2array(app.UITable.Data(:,5));
                Label_ID = table2array(app.UITable.Data(:,6));
                Channels = table2array(app.UITable.Data(:,3));
                Recording_Sessions = table2array(app.UITable.Data(:,8));
                Date = table2array(app.UITable.Data(:,10));
                Notes = table2array(app.UITable.Data(:,12));
            ColumnName = {'Sig_Channels', 'Channels', 'Label', 'Label_ID', 'Recording_Sessions', 'Date', 'Notes'};
            Data_Table = table(Sig_Channels, Channels, Label, Label_ID, Recording_Sessions, Date, Notes);
                 [File_Name, ~] = uigetfile({'*.csv'});
                 File = readtable(File_Name);
                 T = standardizeMissing(File, {'NaN'});
                 T = table2cell(T);
                 Data_Table = table2cell(Data_Table);
                 Final_Table = [ColumnName; T; Data_Table];
                 Final_Table = cell2table(Final_Table);
                 writetable(Final_Table, File_Name,'WriteVariableNames', false); 
                 f = msgbox('LabelCSV appended');
                 app.CSVNameNoExtensionEditField.Value = '';    
        end

        % Button pushed function: GenerateCSVButton
        function GenerateCSVButtonPushed(app, event)
                Sig_Channels = table2array(app.UITable.Data(:,1));
                Label = table2array(app.UITable.Data(:,5));
                Label_ID = table2array(app.UITable.Data(:,6));
                Channels = table2array(app.UITable.Data(:,3));
                Recording_Sessions = table2array(app.UITable.Data(:,8));
                Date = table2array(app.UITable.Data(:,10));
                Notes = table2array(app.UITable.Data(:,12));
           Data_Table = table(Sig_Channels,Channels, Label, Label_ID, Recording_Sessions, Date, Notes);
           ColumnName = {'Sig_Channels', 'Channels', 'Label', 'Label_ID', 'Recording_Sessions', 'Date', 'Notes'};
           Final_Table = [ColumnName; Data_Table];
              if strcmp(app.CSVNameNoExtensionEditField.Value,'')
                     f = errordlg('Labels.csv Name Not Specified','Label Error');
                  else
                    string = strcat(app.CSVNameNoExtensionEditField.Value,'.Labels.csv');
                     writetable(Final_Table, string, 'WriteVariableNames', false);
                     f = msgbox('LabelCSV generated');
                     app.CSVNameNoExtensionEditField.Value = '';
              end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.LoadFileButton = uibutton(app.Panel, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.FileNameLabel = uilabel(app.Panel);
            app.FileNameLabel.Text = 'File Name:';
            app.FileNameDisplay = uilabel(app.Panel);
            app.FileNameDisplay.Text = '';
            app.LabelPanel = uipanel(app.UIFigure);
            app.LabelPanel.Title = 'Label:';
            app.UITable = uitable(app.LabelPanel);
            app.UITable.ColumnName = {'Sig Channel'; 'Set Channel'; 'Channel'; 'Set Label and Label ID'; 'Label'; 'Label ID'; 'Set Recording Session'; 'Recording Session'; 'Set Date'; 'Date (YYYYMMDD)'; 'Set Notes'; 'Notes'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true false true false false true false true false true false];
            app.SelectAllButton = uibutton(app.LabelPanel, 'push');
            app.SelectAllButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButtonPushed, true);
            app.SelectAllButton.Text = 'Select All';
            app.SelectUnselectedButton = uibutton(app.LabelPanel, 'push');
            app.SelectUnselectedButton.ButtonPushedFcn = createCallbackFcn(app, @SelectUnselectedButtonPushed, true);
            app.SelectUnselectedButton.Text = 'Select Unselected';
            app.DeselectAllButton = uibutton(app.LabelPanel, 'push');
            app.DeselectAllButton.ButtonPushedFcn = createCallbackFcn(app, @DeselectAllButtonPushed, true);
            app.DeselectAllButton.Text = 'Deselect All';
            app.SelectAllButton_2 = uibutton(app.LabelPanel, 'push');
            app.SelectAllButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButton_2Pushed, true);
            app.SelectAllButton_2.Text = 'Select All';
            app.SelectUnselectedButton_2 = uibutton(app.LabelPanel, 'push');
            app.SelectUnselectedButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectUnselectedButton_2Pushed, true);
            app.SelectUnselectedButton_2.Text = 'Select Unselected';
            app.DeselectAllButton_2 = uibutton(app.LabelPanel, 'push');
            app.DeselectAllButton_2.ButtonPushedFcn = createCallbackFcn(app, @DeselectAllButton_2Pushed, true);
            app.DeselectAllButton_2.Text = 'Deselect All';
            app.DeselectAllButton_3 = uibutton(app.LabelPanel, 'push');
            app.DeselectAllButton_3.ButtonPushedFcn = createCallbackFcn(app, @DeselectAllButton_3Pushed, true);
            app.DeselectAllButton_3.Text = 'Deselect All';
            app.DeselectAllButton_4 = uibutton(app.LabelPanel, 'push');
            app.DeselectAllButton_4.ButtonPushedFcn = createCallbackFcn(app, @DeselectAllButton_4Pushed, true);
            app.DeselectAllButton_4.Text = 'Deselect All';
            app.DeselectAllButton_5 = uibutton(app.LabelPanel, 'push');
            app.DeselectAllButton_5.ButtonPushedFcn = createCallbackFcn(app, @DeselectAllButton_5Pushed, true);
            app.DeselectAllButton_5.Text = 'Deselect All';
            app.SelectUnselectedButton_3 = uibutton(app.LabelPanel, 'push');
            app.SelectUnselectedButton_3.ButtonPushedFcn = createCallbackFcn(app, @SelectUnselectedButton_3Pushed, true);
            app.SelectUnselectedButton_3.Text = 'Select Unselected';
            app.SelectAllButton_3 = uibutton(app.LabelPanel, 'push');
            app.SelectAllButton_3.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButton_3Pushed, true);
            app.SelectAllButton_3.Text = 'Select All';
            app.SelectAllButton_4 = uibutton(app.LabelPanel, 'push');
            app.SelectAllButton_4.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButton_4Pushed, true);
            app.SelectAllButton_4.Text = 'Select All';
            app.SelectAllButton_5 = uibutton(app.LabelPanel, 'push');
            app.SelectAllButton_5.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButton_5Pushed, true);
            app.SelectAllButton_5.Text = 'Select All';
            app.SelectUnselectedButton_4 = uibutton(app.LabelPanel, 'push');
            app.SelectUnselectedButton_4.ButtonPushedFcn = createCallbackFcn(app, @SelectUnselectedButton_4Pushed, true);
            app.SelectUnselectedButton_4.Text = 'Select Unselected';
            app.SelectUnselectedButton_5 = uibutton(app.LabelPanel, 'push');
            app.SelectUnselectedButton_5.ButtonPushedFcn = createCallbackFcn(app, @SelectUnselectedButton_5Pushed, true);
            app.SelectUnselectedButton_5.Text = 'Select Unselected';
            app.ChannelEditFieldLabel = uilabel(app.LabelPanel);
            app.ChannelEditFieldLabel.Text = 'Channel:';
            app.ChannelEditField = uieditfield(app.LabelPanel, 'text');
            app.SetChannelButton = uibutton(app.LabelPanel, 'push');
            app.SetChannelButton.ButtonPushedFcn = createCallbackFcn(app, @SetChannelButtonPushed, true);
            app.SetChannelButton.Text = 'Set Channel';
            app.LabelEditFieldLabel = uilabel(app.LabelPanel);
            app.LabelEditFieldLabel.Text = 'Label :';
            app.LabelEditField = uieditfield(app.LabelPanel, 'text');
            app.LabelIDEditFieldLabel = uilabel(app.LabelPanel);
            app.LabelIDEditFieldLabel.Text = 'Label ID:';
            app.LabelIDEditField = uieditfield(app.LabelPanel, 'text');
            app.RecordingSessionEditFieldLabel = uilabel(app.LabelPanel);
            app.RecordingSessionEditFieldLabel.Text = 'Recording Session:';
            app.RecordingSessionEditField = uieditfield(app.LabelPanel, 'text');
            app.SetLabelandLabelIDButton = uibutton(app.LabelPanel, 'push');
            app.SetLabelandLabelIDButton.ButtonPushedFcn = createCallbackFcn(app, @SetLabelandLabelIDButtonPushed, true);
            app.SetLabelandLabelIDButton.Text = 'Set Label and Label ID';
            app.SetRecordingSessionButton = uibutton(app.LabelPanel, 'push');
            app.SetRecordingSessionButton.ButtonPushedFcn = createCallbackFcn(app, @SetRecordingSessionButtonPushed, true);
            app.SetRecordingSessionButton.Text = 'Set Recording Session';
            app.DateEditFieldLabel = uilabel(app.LabelPanel);
            app.DateEditFieldLabel.Text = 'Date:';
            app.DateEditField = uieditfield(app.LabelPanel, 'text');
            app.SetDateButton = uibutton(app.LabelPanel, 'push');
            app.SetDateButton.ButtonPushedFcn = createCallbackFcn(app, @SetDateButtonPushed, true);
            app.SetDateButton.Text = 'Set Date';
            app.NotesEditFieldLabel = uilabel(app.LabelPanel);
            app.NotesEditFieldLabel.Text = 'Notes:';
            app.NotesEditField = uieditfield(app.LabelPanel, 'text');
            app.SetNotesButton = uibutton(app.LabelPanel, 'push');
            app.SetNotesButton.ButtonPushedFcn = createCallbackFcn(app, @SetNotesButtonPushed, true);
            app.SetNotesButton.Text = 'Set Notes';
            app.GenerateCSVPanel = uipanel(app.UIFigure);
            app.GenerateCSVPanel.Title = 'GenerateCSV:';
            app.CSVNameNoExtensionEditFieldLabel = uilabel(app.GenerateCSVPanel);
            app.CSVNameNoExtensionEditFieldLabel.Text = 'CSV Name (No Extension):';
            app.CSVNameNoExtensionEditField = uieditfield(app.GenerateCSVPanel, 'text');
            app.GenerateCSVButton = uibutton(app.GenerateCSVPanel, 'push');
            app.GenerateCSVButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateCSVButtonPushed, true);
            app.GenerateCSVButton.Text = 'Generate CSV';
            app.AppendtoExistingCSVButton = uibutton(app.GenerateCSVPanel, 'push');
            app.AppendtoExistingCSVButton.ButtonPushedFcn = createCallbackFcn(app, @AppendtoExistingCSVButtonPushed, true);
            app.AppendtoExistingCSVButton.Text = 'Append to Existing CSV';
            app.ClearTableButton = uibutton(app.UIFigure, 'push');
            app.ClearTableButton.Text = 'Clear Table';
        end
    end

    methods (Access = public)

        % Construct app
        function app = LabelCSVGenerator

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end